classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        regresinPanel                 matlab.ui.container.Panel
        AgregadatosparapredecirLabel  matlab.ui.control.Label
        AgregardatosButton            matlab.ui.control.Button
        GREScoreEditField             matlab.ui.control.NumericEditField
        GREScoreEditFieldLabel        matlab.ui.control.Label
        ChanceOfAdmitEditField        matlab.ui.control.NumericEditField
        ChanceOfAdmitEditFieldLabel   matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        AjustarmodelosButton          matlab.ui.control.Button
        cargardatosButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            [filename, filepath] = uigetfile('*.csv', 'Seleccionar archivo CSV');
data = readmatrix(fullfile(filepath, filename));

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: cargardatosButton
        function cargardatosButtonPushed(app, event)
            datos = readtable("Admission_Predict.xlsx");
            app.UITable.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName= columnas;

        end

        % Value changed function: ChanceOfAdmitEditField
        function ChanceOfAdmitEditFieldValueChanged(app, event)
            value = app.ChanceOfAdmitEditField.Value;
            y=value;
        end

        % Button pushed function: AjustarmodelosButton
        function AjustarmodelosButtonPushed(app, event)
            % Obtén los valores de los ejes X e Y desde la tabla
            tableData = app.UITable.Data;
            x= tableData(:,2);
            y= tableData(:,9);
            x = table2array(x);
            y = table2array(y);

            X = [ones(size(x)), x];  % Add a column of ones for the intercept term
            coeffs = X \ y;  % Calculate the regression coefficients
            intercept = coeffs(1);
            slope = coeffs(2);
            plot(app.UIAxes, x, y, 'o');  % Plot the data points
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, slope*x + intercept);  % Plot the regression line
            hold(app.UIAxes, 'off');


        end

        % Button pushed function: AgregardatosButton
        function AgregardatosButtonPushed(app, event)
            datosTabla = app.UITable.Data;
            valor = app.ChanceOfAdmitEditField.Value;
            valor1 = app.GREScoreEditField.Value;
            nuevoValorCelda = num2cell(valor);
            nuevoValorCelda1 = num2cell(valor1);
            
            columna = 9;
            columna1 = 2;
            datosTabla(end+1, columna) = nuevoValorCelda;
            datosTabla(end, columna1) = nuevoValorCelda1;

            app.UITable.Data = datosTabla;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.regresinPanel = uipanel(app.UIFigure);
            app.regresinPanel.Title = 'regresión';
            app.UIAxes = uiaxes(app.regresinPanel);
            title(app.UIAxes, 'Regresión lineal')
            xlabel(app.UIAxes, 'GRE Score')
            ylabel(app.UIAxes, 'Chance of Admit')
            zlabel(app.UIAxes, 'Z')
            app.cargardatosButton = uibutton(app.regresinPanel, 'push');
            app.cargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @cargardatosButtonPushed, true);
            app.cargardatosButton.Text = 'cargar datos';
            app.AjustarmodelosButton = uibutton(app.regresinPanel, 'push');
            app.AjustarmodelosButton.ButtonPushedFcn = createCallbackFcn(app, @AjustarmodelosButtonPushed, true);
            app.AjustarmodelosButton.Text = 'Ajustar modelos';
            app.UITable = uitable(app.regresinPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ChanceOfAdmitEditFieldLabel = uilabel(app.regresinPanel);
            app.ChanceOfAdmitEditFieldLabel.Text = 'Chance Of Admit';
            app.ChanceOfAdmitEditField = uieditfield(app.regresinPanel, 'numeric');
            app.ChanceOfAdmitEditField.ValueChangedFcn = createCallbackFcn(app, @ChanceOfAdmitEditFieldValueChanged, true);
            app.GREScoreEditFieldLabel = uilabel(app.regresinPanel);
            app.GREScoreEditFieldLabel.Text = 'GRE Score';
            app.GREScoreEditField = uieditfield(app.regresinPanel, 'numeric');
            app.AgregardatosButton = uibutton(app.regresinPanel, 'push');
            app.AgregardatosButton.ButtonPushedFcn = createCallbackFcn(app, @AgregardatosButtonPushed, true);
            app.AgregardatosButton.Text = 'Agregar datos ';
            app.AgregadatosparapredecirLabel = uilabel(app.regresinPanel);
            app.AgregadatosparapredecirLabel.Text = 'Agrega datos para predecir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end